classdef MUSE_SETTING < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Analysis_Package_Selector    matlab.ui.Figure
        PresstofinishPleaseseethecommandwindowButton  matlab.ui.control.Button
        askCPU                       matlab.ui.control.Label
        BrainE                       matlab.ui.control.DropDown
        DoYouneedthecerebellumLabel  matlab.ui.control.Label
        Cerrebellum                  matlab.ui.control.DropDown
        ComputerCluster              matlab.ui.control.DropDown
        DoYouhavecomputingclusterDropDownLabel  matlab.ui.control.Label
        FormoreoptionspleasereferMUSEsmanualButton  matlab.ui.control.Button
        YourMUSESettingButton        matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: 
        % PresstofinishPleaseseethecommandwindowButton
        function PresstofinishPleaseseethecommandwindowButtonPushed(app, event)
            global MUSE_parameters
            MUSE_parameters.cluster = app.ComputerCluster.Value;
            MUSE_parameters.cb = app.Cerrebellum.Value;
            MUSE_parameters.brain = app.BrainE.Value;

            if MUSE_parameters.cluster(1) == 'Y'
               Cluster  = '';
            else
               Cluster  = ' -Q';
            end

            if MUSE_parameters.cb(1) == 'Y'
               Cb  = '';
            else
               Cb  = ' -C';
            end

            if MUSE_parameters.brain(1) == 'Y'
                muse_command = ['muse ' '-i ' (MUSE_parameters.inputfile) ' ' '-D ' (MUSE_parameters.outputpath) Cluster Cb];
            else
                muse_command = ['muse ' '-i ' (MUSE_parameters.inputfile) ' ' '-D ' (MUSE_parameters.outputpath) Cluster Cb ' -B'];
            end
            
            disp('----run this line in your MUSE:');
            disp((muse_command));
            disp('----See more options in MUSE manual')
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Analysis_Package_Selector = uifigure('Visible', 'off');
            app.Analysis_Package_Selector.Name = 'MUSE Setting';
            app.CloseButton = uibutton(app.Analysis_Package_Selector, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.YourMUSESettingButton = uibutton(app.Analysis_Package_Selector, 'push');
            app.YourMUSESettingButton.Text = 'Your MUSE Setting';
            app.FormoreoptionspleasereferMUSEsmanualButton = uibutton(app.Analysis_Package_Selector, 'push');
            app.FormoreoptionspleasereferMUSEsmanualButton.Text = 'For more options, please refer MUSE''s manual';
            app.DoYouhavecomputingclusterDropDownLabel = uilabel(app.Analysis_Package_Selector);
            app.DoYouhavecomputingclusterDropDownLabel.Text = 'Do You have computing cluster?';
            app.ComputerCluster = uidropdown(app.Analysis_Package_Selector);
            app.ComputerCluster.Items = {'Yes', 'No'};
            app.ComputerCluster.Value = 'Yes';
            app.Cerrebellum = uidropdown(app.Analysis_Package_Selector);
            app.Cerrebellum.Items = {'Yes', 'No'};
            app.Cerrebellum.Value = 'Yes';
            app.DoYouneedthecerebellumLabel = uilabel(app.Analysis_Package_Selector);
            app.DoYouneedthecerebellumLabel.Text = 'Do You  need the cerebellum?';
            app.BrainE = uidropdown(app.Analysis_Package_Selector);
            app.BrainE.Items = {'Yes', 'No'};
            app.BrainE.Value = 'Yes';
            app.askCPU = uilabel(app.Analysis_Package_Selector);
            app.askCPU.Text = 'Is the input without the skull?';
            app.PresstofinishPleaseseethecommandwindowButton = uibutton(app.Analysis_Package_Selector, 'push');
            app.PresstofinishPleaseseethecommandwindowButton.ButtonPushedFcn = createCallbackFcn(app, @PresstofinishPleaseseethecommandwindowButtonPushed, true);
            app.PresstofinishPleaseseethecommandwindowButton.Text = 'Press to finish. Please see the command window.';
            % Show the figure after all components are created
            app.Analysis_Package_Selector.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MUSE_SETTING

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Analysis_Package_Selector)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Analysis_Package_Selector)
        end
    end
end